classdef TaesApp < matlab.apps.AppBase
    %TAESAPP Thermoeconomic Analysis of Energy Systems
    %   TaesLab makes thermoeconomic analysis and diagnosis of energy
    %   systems.
    %   - In the left panel, the data model and parameters could be
    %   selected
    %   - In the right panel, the results to be viewed as tables or graphs
    %   in the central panel could be selected.
    %   - Results could be saved as XLSX (Excel) or CVS (plain files)

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveModelMenu                 matlab.ui.container.Menu
        SaveResultsMenu               matlab.ui.container.Menu
        SaveDataModelMenu             matlab.ui.container.Menu
        SaveSummaryMenu               matlab.ui.container.Menu
        SaveDiagramMenu               matlab.ui.container.Menu
        CloseMenu                     matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        GridLayout2                   matlab.ui.container.GridLayout
        InfoPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        Hyperlink                     matlab.ui.control.Hyperlink
        LogField                      matlab.ui.control.Label
        ResultTablesPanel             matlab.ui.container.Panel
        Tree                          matlab.ui.container.Tree
        MdNode                        matlab.ui.container.TreeNode
        PsNode                        matlab.ui.container.TreeNode
        TsNode                        matlab.ui.container.TreeNode
        TaNode                        matlab.ui.container.TreeNode
        WaNode                        matlab.ui.container.TreeNode
        TdNode                        matlab.ui.container.TreeNode
        SrNode                        matlab.ui.container.TreeNode
        TabGroup                      matlab.ui.container.TabGroup
        WelcomeTab                    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        IndexTab                      matlab.ui.container.Tab
        TableLabel                    matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        TablesTab                     matlab.ui.container.Tab
        Table                         matlab.ui.control.HTML
        GraphTab                      matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        LogTab                        matlab.ui.container.Tab
        TableLog                      matlab.ui.control.Table
        InputParametersPanel          matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        WasteFlowDropDown             matlab.ui.control.DropDown
        WasteFlowLabel                matlab.ui.control.Label
        CheckBox                      matlab.ui.control.CheckBox
        RecyclingAnalysisLabel        matlab.ui.control.Label
        DataModelEditField            matlab.ui.control.EditField
        ReferenceStateDropDown        matlab.ui.control.DropDown
        OperationStateDropDown        matlab.ui.control.DropDown
        ReferenceStateLabel           matlab.ui.control.Label
        ResourceCostDropDown          matlab.ui.control.DropDown
        ResultsFileLabel              matlab.ui.control.Label
        CostTablesDropDown            matlab.ui.control.DropDown
        DiagnosisMethodDropDown       matlab.ui.control.DropDown
        DiagnosisMethodDropDownLabel  matlab.ui.control.Label
        CostTablesLabel               matlab.ui.control.Label
        ResourceCostDropDownLabel     matlab.ui.control.Label
        OperationStateDropDownLabel   matlab.ui.control.Label
        DataModelEditFieldLabel       matlab.ui.control.Label
        SummaryResultsLabel           matlab.ui.control.Label
        SummaryCheckBox               matlab.ui.control.CheckBox
        OutputFileEditField           matlab.ui.control.EditField
        LoadDataButton                matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ContextMenu                   matlab.ui.container.ContextMenu
        OpenAsFigMenu                 matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        SaveInWorkspaceMenu           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Model   % Thermoeconomic Model
    end
    
    properties (Access = private)
        ResultFile % Result file name
        LogColors  % Color Log definition
        CurrentNode % Selected group of tables
        Colorbar   % Colorbar
        Messages   % App messages logger
    end
    
    methods (Access = private)
        function InitInputProperties(app)
            % Initialize Input parameters
            app.DataModelEditField.BackgroundColor=[1 0.5 0.5];
            app.DataModelEditField.Value='No Model Available';
            app.SaveButton.Enable=false;
			app.CostTablesDropDown.Enable=false;
            app.CostTablesDropDown.Value=cType.DEFAULT_COST_TABLES;
            app.DiagnosisMethodDropDown.Enable=false;
            app.DiagnosisMethodDropDown.Items={cType.DEFAULT_DIAGNOSIS};
			app.DiagnosisMethodDropDown.Value=cType.DEFAULT_DIAGNOSIS;
            app.ReferenceStateDropDown.Enable=false;
            app.ReferenceStateDropDown.Items={'Reference','Operation'};
            app.ReferenceStateDropDown.Value='Reference';
            app.OperationStateDropDown.Enable=false;
            app.OperationStateDropDown.Items={'Reference','Operation'};
            app.OperationStateDropDown.Value='Reference';
            app.ResourceCostDropDown.Enable=false;
            app.ResourceCostDropDown.Items={'Base'};
            app.ResourceCostDropDown.Value='Base';
            app.SummaryCheckBox.Enable=false;
            app.SummaryCheckBox.Value=false;
            app.LogField.Text='';
            app.Model=cStatusLogger;
            app.Messages=cStatusLogger;
            app.LogColors={'magenta','red','black','blue'};
            TaesApp.ClearNode(app.MdNode);
            TaesApp.ClearNode(app.PsNode);
            TaesApp.ClearNode(app.TsNode);
            TaesApp.ClearNode(app.TaNode);
            TaesApp.ClearNode(app.WaNode);
            TaesApp.ClearNode(app.TdNode);
            TaesApp.ClearNode(app.SrNode)
            app.TableLabel.Visible=false;
            app.UITable.Visible=false;
            app.UIAxes.Visible=false;
            app.TableLog.Visible=false;
            app.CurrentNode=[];
        end

        % Show Model Data
        function ShowModelData(app)
            obj=app.Model.DataModel.ModelInfo;
            if obj.isValid
                app.UITable.Visible=false;
                app.UIAxes.Visible=false;
                TaesApp.CreateNode(app.MdNode,obj);
            end
        end


        % Show productive structure
        function ProductiveStructure(app)
			obj=app.Model.productiveStructure;
            if obj.isValid
                app.UITable.Visible=false;
                app.UIAxes.Visible=false;
                TaesApp.CreateNode(app.PsNode,obj);
            end
        end

        % Show exergy tables
        function ThermoeconomicState(app)
            obj=app.Model.thermoeconomicState;
            if obj.isValid
                app.UITable.Visible=false;
                app.UIAxes.Visible=false;
                TaesApp.CreateNode(app.TsNode,obj);
            end
        end

        % Show thermoeconomic analysis results
        function ThermoeconomicAnalysis(app)       
            obj=app.Model.thermoeconomicAnalysis;
            if obj.isValid
                app.UITable.Visible=false;
                app.UIAxes.Visible=false;
                TaesApp.CreateNode(app.TaNode,obj);
            end
        end

        % Show thermoeconomic diagnosis results
        function ThermoeconomicDiagnosis(app)
            obj=app.Model.thermoeconomicDiagnosis;
            if ~isempty(obj) && obj.isValid
                app.UITable.Visible=false;
                app.UIAxes.Visible=false;
                TaesApp.CreateNode(app.TdNode,obj);
                app.TdNode.UserData=getFuelImpact(app.Model);
                app.ShowNodeData(app.TdNode)
            end   
        end

        % Show waste analysis results
        function WasteAnalysis(app)
            obj=app.Model.wasteAnalysis;
            if obj.isValid
                app.UITable.Visible=false;
                app.UIAxes.Visible=false;
                TaesApp.CreateNode(app.WaNode,obj);
            end
        end


        % Show the Summary Results
        function SummaryResults(app)
            obj=app.Model.summaryResults;
            if ~isempty(obj) && obj.isValid
                app.UITable.Visible=false;
                app.UIAxes.Visible=false;
                TaesApp.CreateNode(app.SrNode,obj);
                app.ShowNodeData(app.SrNode)
            end
        end

        function ShowNodeData(app,node)
        % Expand a node and show index table
            app.CurrentNode=node;
            app.CurrentNode.expand;
            tbl=app.CurrentNode.NodeData;
            app.ViewIndexTable(tbl);
            app.TabGroup.SelectedTab=app.IndexTab;
            app.LogField.Text=sprintf(' INFO: %s selected',tbl.ResultName);         
        end

        
        % Show the graph of a table result
        function GraphCost(app,tbl)
            obj=cGraphResults(tbl);
            N=numel(obj.Categories);
            M=numel(obj.Legend);
            app.UIAxes.Visible='off';
            app.UIAxes.Colormap=turbo(M);
            % Plot the bar graph
            b=bar(obj.yValues,...
                    'EdgeColor','none','BarWidth',0.5,...
                    'BarLayout','stacked',...
                    'BaseValue',obj.BaseLine,...
                    'FaceColor','flat',...
                    'Parent',app.UIAxes);
            for i=1:M
                b(i).CData=app.UIAxes.Colormap(i,:);
            end
            title(app.UIAxes,obj.Title,'FontSize',12);
            xlabel(app.UIAxes,obj.xLabel,'FontSize',10);
            ylabel(app.UIAxes,obj.yLabel,'FontSize',10);
            legend(app.UIAxes,obj.Legend,'FontSize',8);
            if ~isempty(app.Colorbar)
                app.Colorbar.Visible='off';
            end
            app.UIAxes.XTick=(1:N); 
            app.UIAxes.XTickLabel=obj.Categories;
            app.UIAxes.Legend.Location='northeastoutside';
            app.UIAxes.Legend.Orientation='vertical';
            app.UIAxes.Visible='on';
        end

        % Graph Summary
        function GraphSummary(app,tbl)
            if tbl.isFlowsTable
                res=app.Tree.SelectedNodes.Parent.NodeData;
                var=res.Info.getDefaultFlowVariables;
                obj=cGraphResults(tbl,var);
            else
                return
            end
            M=numel(obj.Legend);
            app.UIAxes.Visible='off';
            app.UIAxes.Colormap=turbo(M);
            % Plot the bar graph
            b=bar(obj.xValues, obj.yValues,...
                    'EdgeColor','none','BarWidth',0.5,...
                    'BaseValue',obj.BaseLine,...
                    'FaceColor','flat',...
                    'Parent',app.UIAxes);
            for i=1:M
                b(i).CData=app.UIAxes.Colormap(i,:);
            end
            title(app.UIAxes,obj.Title,'FontSize',12);
            xlabel(app.UIAxes,obj.xLabel,'FontSize',10);
            ylabel(app.UIAxes,obj.yLabel,'FontSize',10);
            legend(app.UIAxes,obj.Legend,'FontSize',8);
            if ~isempty(app.Colorbar)
                app.Colorbar.Visible='off';
            end
            app.UIAxes.XTickLabel=obj.Categories;
            app.UIAxes.Legend.Location='northeastoutside';
            app.UIAxes.Legend.Orientation='vertical';
            app.UIAxes.Visible='on';
        end

        % Graph Diagnosis
        function GraphDiagnosis(app,tbl)
            obj=cGraphResults(tbl);
            N=numel(obj.Categories);
            M=numel(obj.Legend);
            app.UIAxes.Visible='off';
            app.UIAxes.Colormap=turbo(M);
            % Plot the bar graph
            b=bar(obj.yValues,...
                    'EdgeColor','none','BarWidth',0.5,...
                    'BarLayout','stacked',...
                    'BaseValue',obj.BaseLine,...
                    'FaceColor','flat',...
                    'Parent',app.UIAxes);
            for i=1:M
                b(i).CData=app.UIAxes.Colormap(i,:);
            end
            bs=b.BaseLine;
            bs.BaseValue=0.0;
            bs.LineStyle='-';
            bs.Color=[0.6,0.6,0.6];
            title(app.UIAxes,obj.Title,'FontSize',12);
            xlabel(app.UIAxes,obj.xLabel,'FontSize',10);
            ylabel(app.UIAxes,obj.yLabel,'FontSize',10);
            legend(app.UIAxes,obj.Legend,'FontSize',8);
            if ~isempty(app.Colorbar)
                app.Colorbar.Visible='off';
            end
            app.UIAxes.XTick=(1:N); 
            app.UIAxes.XTickLabel=obj.Categories;
            app.UIAxes.Legend.Location='northeastoutside';
            app.UIAxes.Legend.Orientation='vertical';
            app.UIAxes.Visible='on';
        end

        % Show FP Diagrams using plot digraph function
        function ShowDiagramFP(app,tbl)
            % Get digraph from table FP
            obj=cGraphResults(tbl);
            r=(0:0.1:1); red2blue=[r.^0.4;0.2*(1-r);0.8*(1-r)]';
            app.UIAxes.Colormap=red2blue;
            plot(app.UIAxes,obj.xValues,"Layout","auto","EdgeCData",obj.xValues.Edges.Weight,"EdgeColor","flat");
            app.Colorbar=colorbar(app.UIAxes);
            app.Colorbar.Label.String=['Exergy ', tbl.Unit];
            app.UIAxes.Title.String=obj.Title;
            app.UIAxes.XLabel.String='';
            app.UIAxes.YLabel.String='';
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            app.UIAxes.XGrid = 'off';
            app.UIAxes.YGrid = 'off';
            legend(app.UIAxes,'off');
            app.UIAxes.Visible='on';
        end

        % Show Waste Allocation Graph
        function GraphWasteAllocation(app,tbl)
            % Get graph Properties
            obj=cGraphResults(tbl);
            % Plot the bar graph
            app.UIAxes.Visible='off';
            if ~isempty(app.Colorbar)
                app.Colorbar.Visible='off';
            end
            bar(obj.xValues,obj.yValues',...
                'EdgeColor','none',...
                'BarLayout','stacked',...
                'Horizontal','on',...
                'Parent',app.UIAxes);
            title(app.UIAxes,obj.Title,'FontSize',14);
            xlabel(app.UIAxes,obj.xLabel,'FontSize',12);
            ylabel(app.UIAxes,obj.yLabel,'FontSize',12);
            legend(app.UIAxes,obj.Categories,'FontSize',8);
            app.UIAxes.Legend.Location='bestoutside';
            app.UIAxes.Legend.Orientation='horizontal';
            xtick=(0:10:100);
            app.UIAxes.XTick = xtick;
            app.UIAxes.XTickLabel=arrayfun(@(x) sprintf('%3d',x),xtick,'UniformOutput',false);
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'off';
            % Show the figure after all components are created
            app.UIAxes.Visible = 'on';
        end
        
        % Show logger messages
        function ShowLogger(app,log)
            app.Messages.addLogger(log);
            [data,index]=app.Messages.tableLogger;
            for i=1:numel(app.LogColors)
                rows=find(index==i);
                if ~isempty(rows)
                    style=uistyle('FontColor',app.LogColors{i});
                    addStyle(app.TableLog,style,'row',rows);
                end
            end
            app.TableLog.Data=data;
            app.TabGroup.SelectedTab=app.LogTab;
            app.TableLog.Visible=true;
        end

        % Clear Tab contents befere new results calculation
        function ClearTabContent(app)
            app.Table.Visible=false;
            app.UIAxes.Visible=false;
            if ~isempty(app.Colorbar)
                app.Colorbar.Visible=false;
            end
            if ~isempty(app.UIAxes.Legend)
                app.UIAxes.Legend.Visible=false;
            end
            delete(app.UIAxes.Children)
            app.LogField.Text='';
        end
        
        % Show table in table panel
        function ViewTable(app,tbl)
            vh=cBuildHTML(tbl);
            if isValid(vh)
                app.Table.HTMLSource=vh.getMarkupHTML;
            else
                printLogger(vh);
            end
            app.Table.Visible=true;
            app.TabGroup.SelectedTab=app.TablesTab;
        end

        % Show the index table in table panel
        function ViewIndexTable(app,res)
            app.TableLabel.Text=res.ResultName;
            tbl=res.getTableIndex;
            app.UITable.ColumnWidth={'auto','9x','1x'};
            app.UITable.Data=[tbl.RowNames',tbl.Data];
            app.TableLabel.Visible=true;
            app.UITable.Visible=true;
            %app.TableIndex=tbl;
            app.TabGroup.SelectedTab=app.IndexTab;
        end
        
        % Show table in graph panel
        function ViewGraph(app,tbl)
            if tbl.isGraph
                switch tbl.GraphType
                    case cType.GraphType.COST
                        app.GraphCost(tbl)
                    case cType.GraphType.DIAGNOSIS
                        app.GraphDiagnosis(tbl)
                    case cType.GraphType.DIAGRAM_FP
                        app.ShowDiagramFP(tbl)
                    case cType.GraphType.SUMMARY
                        app.GraphSummary(tbl)
                    case cType.GraphType.WASTE_ALLOCATION
                        app.GraphWasteAllocation(tbl)
                end
            end            
        end
    end
    
    methods(Access=private,Static)
        % Create tree node from result tables
        function CreateNode(node,res)
            TaesApp.ClearNode(node);
            tables=fieldnames(res.Tables);
            node.NodeData=res;
            for i=1:numel(tables)
                tn=uitreenode(node);
                tn.Text=tables{i};
                tn.NodeData=res.Tables.(tables{i});
            end
            collapse(node);
        end
        
        % Delete the tree nodes
        function ClearNode(node)
            children=node.Children;
            if ~isempty(children)
                children.delete;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tables=cType.CostTablesOptions;
            app.CostTablesDropDown.Items=tables;
            outputFileName=strcat(cType.RESULT_FILE,'.xlsx');
            app.OutputFileEditField.Value=outputFileName;
			app.ResultFile=[pwd,filesep,outputFileName];
            %path=fileparts(mfilename('fullpath'));
            %app.Image.ImageSource=cType.getTaesImage(path);
            app.InitInputProperties;
        end

        % Callback function: LoadDataButton, OpenMenu
        function LoadData(app, event)
            % Load datafile
            app.InitInputProperties;
            [file,path]=uigetfile({'*.json;*.xml*;.csv;*.xlsx;*.mat','Suported Data Models'});
            if file
			    cd(path);
			    app.DataModelEditField.Value=file;
            else
                logtext=' ERROR: No Model Available';
                app.LogField.Text=logtext; 
                return
            end
			% Read and Check Data Model
            app.TableLog.Data=[];
            data=checkDataModel(file);
            % Assing parameters and execute base analysis
            if isValid(data) %Assign parameters
                app.Model=cThermoeconomicModel(data);
                app.DataModelEditField.BackgroundColor=[0.95 1 0.95];
                app.SaveButton.Enable=true;
                app.SaveResultsMenu.Enable=true;
                app.SaveDataModelMenu.Enable=true;
                app.SaveDiagramMenu.Enable=true;
                app.SaveModelMenu.Enable=true;
				stateNames=app.Model.StateNames;
                app.OperationStateDropDown.Enable=true;
                app.OperationStateDropDown.Items=stateNames;
                app.ReferenceStateDropDown.Enable=true;
                app.ReferenceStateDropDown.Items=stateNames;
                if data.NrOfStates>1
                    app.SummaryCheckBox.Enable=true;
                end
                if data.isResourceCost
					sampleNames=app.Model.SampleNames;
                    app.ResourceCostDropDown.Items=sampleNames;
                    app.ResourceCostDropDown.Enable=true;
                    app.CostTablesDropDown.Enable=true;
                    app.CostTablesDropDown.Value=cType.DEFAULT_COST_TABLES;
                end
                dnames=cType.DiagnosisOptions;
                if data.isWaste
					app.DiagnosisMethodDropDown.Items=dnames;
            		app.DiagnosisMethodDropDown.Value=cType.DEFAULT_DIAGNOSIS;
                else
				    app.DiagnosisMethodDropDown.Items=dnames(1:2);
            		app.DiagnosisMethodDropDown.Value=cType.DEFAULT_DIAGNOSIS;
                end
                logtext=' INFO: Valid Data Model';
                app.ShowModelData;
                app.ProductiveStructure;
                app.ThermoeconomicState;
                app.ThermoeconomicAnalysis;
                app.WasteAnalysis;
            else
                app.DataModelEditField.BackgroundColor=[1 0.5 0.5];
				logtext=' ERROR: Invalid Data Model';
                app.Model.addLogger(data);
                uialert(app.UIFigure,logtext,'Warning');
            end
            app.LogField.Text=logtext; 
            app.ShowLogger(app.Model);
        end

        % Menu selected function: SaveModelMenu
        function SaveModel(app, event)
           % Save the model in the workspace
            if ~isdeployed
                assignin('base', 'model', app.Model);
                app.LogField.Text='INFO: Model saved on workspace';
            end
        end

        % Callback function: SaveButton, SaveResultsMenu
        function SaveResults(app, event)
            % Save the model results
            log=cStatusLogger;
            default_file=strcat(cType.RESULT_FILE,cType.FileExt.XLSX);
			[file,path,ext]=uiputfile({'*.xlsx','XLSX Files';'*.txt','TXT Files'; ...
                '*.csv','CSV Files'},'Select File',default_file);
            if ext % File has been selected
                cd(path)
				filename=fullfile(path,file);
                log=saveModelResults(app.Model,file);
                if isvalid(log)
				    app.ResultFile=filename;
                    app.OutputFileEditField.Value=file;
                    logtext=sprintf(' INFO: Results Available in file %s',file);
                else
                    log.messageLog(cType.WARNING,'Results have NOT saved. ')
                    logtext=sprintf(' WARNING: Results have NOT saved. See Log');
                end
		    else
				logtext=sprintf(' WARNING: Results are NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);
        end

        % Menu selected function: SaveSummaryMenu
        function SaveSummary(app, event)
            % Save the summary results
            log=cStatusLogger;
            default_file=strcat(cType.SUMMARY_FILE,cType.FileExt.XLSX);
			[file,path,ext]=uiputfile({'*.xlsx','XLSX Files';'*.txt','TXT Files'; ...
                '*.csv','CSV Files';'*.mat','MAT Files'},'Select File',default_file);
            if ext % File has been selected
                cd(path);
                log=saveSummary(app.Model,file);
                if isValid(log)
			        logtext=sprintf(' INFO: Summary Results Available in file %s',file);
                else
                    logtext=sprintf(' WARNING: Summary Results are NOT saved. See Log');
                end
            else
                log.messageLog(cType.WARNING,'Summary Results are NOT Available')
				logtext=sprintf(' WARNING: Summary Results are NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);  
        end

        % Menu selected function: SaveDiagramMenu
        function SaveDiagramFP(app, event)
            % Save the diagram FP
            log=cStatusLogger;
            default_file=strcat(cType.DIAGRAM_FILE,cType.FileExt.XLSX);
			[file,path,ext]=uiputfile({'*.xlsx','XLSX Files';'*.csv','CSV Files'},'Select File',default_file);
            if ext % File has been selected
                cd(path);
                log=saveDiagramFP(app.Model,file);
                if isValid(log)
			        logtext=sprintf(' INFO: Summary Results in file %s',file);
                else
                    logtext=sprintf(' WARNING: Summary Results NOT saved. See Log');
                end
            else
                log.messageLog(cType.WARNING,'Summary Results are NOT Available')
				logtext=sprintf(' WARNING: Summary Results NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);    
        end

        % Menu selected function: SaveDataModelMenu
        function SaveDataModel(app, event)
            % Save the Data Model
            log=cStatusLogger;
            default_file=strcat(app.Model.ModelName,cType.FileExt.MAT);
			[file,path,ext]=uiputfile({'*.mat','MAT Files';'*.json','JSON Files';'*.xml','XML files'; ...
                '*.csv','CSV Files';'*.xlsx','XLSX Files'},'Select File',default_file);
            if ext % File has been selected
                cd(path);
                log=saveDataModel(app.Model,file);
                if isValid(log)
			        logtext=sprintf(' INFO: Data Model Available in file %s',file);
                else
                    logtext=sprintf(' WARNING: Data Model is NOT saved. See Log');
                end
            else
                log.messageLog(cType.WARNING,'Data Model is NOT Available');
				logtext=sprintf(' WARNING: Data Model is NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);
        end

        % Callback function: CloseMenu, UIFigure
        function CloseApp(app, event)
            selection = uiconfirm(app.UIFigure,'Close the Application?',...
                'Confirmation');   
            switch selection
                case 'OK'
                    delete(app)                      
                case 'Cancel'
                    return
            end
        end

        % Value changed function: OperationStateDropDown
        function getOperationState(app, event)
            % Select Operation State
            app.Model.State = app.OperationStateDropDown.Value;
            app.ThermoeconomicState;
            app.ThermoeconomicAnalysis;
            app.ClearTabContent;
            if ~isempty(app.CurrentNode)
                app.CurrentNode.collapse;
                app.CurrentNode=[];
            end
            app.DiagnosisMethodDropDown.Enable=app.Model.isDiagnosis;
            if app.Model.isDiagnosis
                app.ThermoeconomicDiagnosis;
            else
                TaesApp.ClearNode(app.TdNode);
            end
        end

        % Value changed function: ReferenceStateDropDown
        function getReferenceState(app, event)
            % Select Reference State
            app.Model.ReferenceState=app.ReferenceStateDropDown.Value;
            app.ClearTabContent;
            if ~isempty(app.CurrentNode)
                app.CurrentNode.collapse;
                app.CurrentNode=[];
            end
            app.DiagnosisMethodDropDown.Enable=app.Model.isDiagnosis;
            if app.Model.isDiagnosis
                app.ThermoeconomicDiagnosis;
            else
                TaesApp.ClearNode(app.TdNode);
            end
        end

        % Value changed function: CostTablesDropDown
        function getCostTables(app, event)
            % Select CostTable property 
            app.Model.CostTables = app.CostTablesDropDown.Value;
            app.ThermoeconomicAnalysis;
            app.ClearTabContent;
            if ~isempty(app.CurrentNode)
                app.CurrentNode.collapse;
                app.CurrentNode=[];
            end
        end

        % Value changed function: ResourceCostDropDown
        function getResourcesSample(app, event)
            % Select Resource Sample
            app.Model.ResourceSample = app.ResourceCostDropDown.Value;
            app.ThermoeconomicAnalysis;
            app.ClearTabContent;
            if ~isempty(app.CurrentNode)
                app.CurrentNode.collapse;
                app.CurrentNode=[];
            end
        end

        % Value changed function: DiagnosisMethodDropDown
        function getDiagnosisMethod(app, event)
            % Select DiagnosisMethod property
            app.Model.DiagnosisMethod = app.DiagnosisMethodDropDown.Value;
            app.ClearTabContent;
            if ~isempty(app.CurrentNode)
                app.CurrentNode.collapse;
                app.CurrentNode=[];
            end
            if app.Model.isDiagnosis
                app.ThermoeconomicDiagnosis;
            else
                TaesApp.ClearNode(app.TdNode);
            end
        end

        % Value changed function: SummaryCheckBox
        function SummaryValueChanged(app, event)
            % Activate Summary Results
            value = app.SummaryCheckBox.Value;
            app.Model.Summary=value;
            app.ClearTabContent;
            if ~isempty(app.CurrentNode)
                app.CurrentNode.collapse;
                app.CurrentNode=[];
            end
            if value
                app.SummaryResults;
            else
                TaesApp.ClearNode(app.SrNode);
            end
            app.SaveSummaryMenu.Enable=value;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % Select tables to view
            selectedNodes = app.Tree.SelectedNodes;
            tbl=selectedNodes.NodeData;
            if isempty(tbl)
                return
            end
            if isa(tbl,'cTable')
                resultNode=selectedNodes.Parent;
                logtext=sprintf(' INFO: %s for State %s. %s ',...
                    resultNode.Text,app.Model.State,resultNode.UserData);
                app.LogField.Text=logtext;
                app.ViewTable(tbl);
                app.ViewGraph(tbl);
                app.CurrentNode=resultNode;
            elseif isa(tbl,'cResultInfo')
                app.ViewIndexTable(tbl);
                app.LogField.Text=sprintf(' INFO: %s selected',tbl.ResultName);    
            end
        end

        % Node expanded function: Tree
        function TreeNodeExpanded(app, event)
            node=event.Node;
            if ~isempty(app.CurrentNode)
                app.CurrentNode.collapse;
            end
            app.CurrentNode=node;
            app.ViewIndexTable(node.NodeData);
            app.TabGroup.SelectedTab=app.IndexTab;
        end

        % Menu selected function: OpenAsFigMenu
        function OpenAsFigMenuSelected(app, event)
            newfigure = figure;
            copyobj(app.UIAxes, newfigure);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            web('https://www.exergoecology.com');
        end

        % Menu selected function: SaveInWorkspaceMenu
        function SaveInWorkspaceMenuSelected(app, event)
            % Save Model Results into workspace
            if ~isdeployed
                assignin('base', 'model', app.Model);
                app.LogField.Text='INFO: Model saved on workspace';
            end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            idx=selection(1);
            tname=app.UITable.RowName{idx};
            tbl=app.Model.getTable(tname);
            app.ViewTable(tbl);
        end

        % Node collapsed function: Tree
        function TreeNodeCollapsed(app, event)
            app.ClearTabContent;
            app.TabGroup.SelectedTab=app.IndexTab; 
            app.CurrentNode=[];            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TAES App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '  File ';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadData, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveModelMenu = uimenu(app.SaveMenu);
            app.SaveModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveModel, true);
            app.SaveModelMenu.Tooltip = {'Save Model as MAT file'};
            app.SaveModelMenu.Enable = 'off';
            app.SaveModelMenu.Text = 'Model';
            app.SaveResultsMenu = uimenu(app.SaveMenu);
            app.SaveResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResults, true);
            app.SaveResultsMenu.Enable = 'off';
            app.SaveResultsMenu.Text = 'Results';
            app.SaveDataModelMenu = uimenu(app.SaveMenu);
            app.SaveDataModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataModel, true);
            app.SaveDataModelMenu.Enable = 'off';
            app.SaveDataModelMenu.Text = 'Data Model';
            app.SaveSummaryMenu = uimenu(app.SaveMenu);
            app.SaveSummaryMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSummary, true);
            app.SaveSummaryMenu.Enable = 'off';
            app.SaveSummaryMenu.Text = 'Summary';
            app.SaveDiagramMenu = uimenu(app.SaveMenu);
            app.SaveDiagramMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDiagramFP, true);
            app.SaveDiagramMenu.Tooltip = {'Save Diagram FP Info'};
            app.SaveDiagramMenu.Enable = 'off';
            app.SaveDiagramMenu.Text = 'Diagram FP';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseApp, true);
            app.CloseMenu.Text = 'Close';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = ' Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.InputParametersPanel = uipanel(app.GridLayout2);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.GridLayout = uigridlayout(app.InputParametersPanel);
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResults, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Select the results file name'};
            app.SaveButton.Text = 'Save Results';
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDataButton.Tooltip = {'Select the data model file'};
            app.LoadDataButton.Text = 'Load Data';
            app.OutputFileEditField = uieditfield(app.GridLayout, 'text');
            app.OutputFileEditField.Editable = 'off';
            app.OutputFileEditField.Tooltip = {'Show the file name to save the results'};
            app.OutputFileEditField.Value = 'ModelResults.xlsx';
            app.SummaryCheckBox = uicheckbox(app.GridLayout);
            app.SummaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @SummaryValueChanged, true);
            app.SummaryCheckBox.Enable = 'off';
            app.SummaryCheckBox.Tooltip = {'Activate Summary Results'};
            app.SummaryCheckBox.Text = '';
            app.SummaryResultsLabel = uilabel(app.GridLayout);
            app.SummaryResultsLabel.Text = 'Summary Results:';
            app.DataModelEditFieldLabel = uilabel(app.GridLayout);
            app.DataModelEditFieldLabel.Text = 'Data Model:';
            app.OperationStateDropDownLabel = uilabel(app.GridLayout);
            app.OperationStateDropDownLabel.Text = 'Operation State:';
            app.ResourceCostDropDownLabel = uilabel(app.GridLayout);
            app.ResourceCostDropDownLabel.Text = 'Resource Cost:';
            app.CostTablesLabel = uilabel(app.GridLayout);
            app.CostTablesLabel.Text = 'Cost Tables:';
            app.DiagnosisMethodDropDownLabel = uilabel(app.GridLayout);
            app.DiagnosisMethodDropDownLabel.Text = 'Diagnosis Method:';
            app.DiagnosisMethodDropDown = uidropdown(app.GridLayout);
            app.DiagnosisMethodDropDown.Items = {'NONE'};
            app.DiagnosisMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @getDiagnosisMethod, true);
            app.DiagnosisMethodDropDown.Enable = 'off';
            app.DiagnosisMethodDropDown.Tooltip = {'Select the diagnosis method to use'};
            app.DiagnosisMethodDropDown.Value = 'NONE';
            app.CostTablesDropDown = uidropdown(app.GridLayout);
            app.CostTablesDropDown.Items = {'DIRECT'};
            app.CostTablesDropDown.ValueChangedFcn = createCallbackFcn(app, @getCostTables, true);
            app.CostTablesDropDown.Enable = 'off';
            app.CostTablesDropDown.Tooltip = {'Select the tables to show'};
            app.CostTablesDropDown.Value = 'DIRECT';
            app.ResultsFileLabel = uilabel(app.GridLayout);
            app.ResultsFileLabel.Text = 'Results File:';
            app.ResourceCostDropDown = uidropdown(app.GridLayout);
            app.ResourceCostDropDown.Items = {'Base'};
            app.ResourceCostDropDown.ValueChangedFcn = createCallbackFcn(app, @getResourcesSample, true);
            app.ResourceCostDropDown.Enable = 'off';
            app.ResourceCostDropDown.Tooltip = {'Select the resources cost sample'};
            app.ResourceCostDropDown.Value = 'Base';
            app.ReferenceStateLabel = uilabel(app.GridLayout);
            app.ReferenceStateLabel.Text = 'Reference State:';
            app.OperationStateDropDown = uidropdown(app.GridLayout);
            app.OperationStateDropDown.Items = {'Reference', 'Operation'};
            app.OperationStateDropDown.ValueChangedFcn = createCallbackFcn(app, @getOperationState, true);
            app.OperationStateDropDown.Enable = 'off';
            app.OperationStateDropDown.Tooltip = {'Select the operation state'};
            app.OperationStateDropDown.Value = 'Reference';
            app.ReferenceStateDropDown = uidropdown(app.GridLayout);
            app.ReferenceStateDropDown.Items = {'Reference'};
            app.ReferenceStateDropDown.ValueChangedFcn = createCallbackFcn(app, @getReferenceState, true);
            app.ReferenceStateDropDown.Enable = 'off';
            app.ReferenceStateDropDown.Tooltip = {'Select Reference State'};
            app.ReferenceStateDropDown.Value = 'Reference';
            app.DataModelEditField = uieditfield(app.GridLayout, 'text');
            app.DataModelEditField.Editable = 'off';
            app.DataModelEditField.Tooltip = {'Show the data model file'};
            app.DataModelEditField.Value = 'No Model Available';
            app.RecyclingAnalysisLabel = uilabel(app.GridLayout);
            app.RecyclingAnalysisLabel.Text = 'Recycling Analysis:';
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.WasteFlowLabel = uilabel(app.GridLayout);
            app.WasteFlowLabel.Text = 'Waste Flow:';
            app.WasteFlowDropDown = uidropdown(app.GridLayout);
            app.WasteFlowDropDown.Items = {'NONE'};
            app.WasteFlowDropDown.Enable = 'off';
            app.WasteFlowDropDown.Tooltip = {'Select the active flow for recycling analysis'};
            app.WasteFlowDropDown.Value = 'NONE';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.GridLayout3 = uigridlayout(app.WelcomeTab);
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'TaesLab.png');
            app.IndexTab = uitab(app.TabGroup);
            app.IndexTab.Tooltip = {'Show the results as tables'};
            app.IndexTab.Title = 'Index';
            app.UITable = uitable(app.IndexTab);
            app.UITable.ColumnName = {'Table'; 'Description'; 'Graph'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Visible = 'off';
            app.TableLabel = uilabel(app.IndexTab);
            app.TableLabel.Visible = 'off';
            app.TableLabel.Text = 'TableLabel';
            app.TablesTab = uitab(app.TabGroup);
            app.TablesTab.Title = 'Tables';
            app.Table = uihtml(app.TablesTab);
            app.Table.Visible = 'off';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Tooltip = {'Show the results as bar graphs'};
            app.GraphTab.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Tooltip = {'Show the log messages'};
            app.LogTab.Title = 'Log';
            app.TableLog = uitable(app.LogTab);
            app.TableLog.ColumnName = {'Type'; 'Class'; 'Text'};
            app.TableLog.RowName = {};
            app.ResultTablesPanel = uipanel(app.GridLayout2);
            app.ResultTablesPanel.Tooltip = {'Select the result tables to show'};
            app.ResultTablesPanel.Title = 'Result Tables';
            app.Tree = uitree(app.ResultTablesPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            app.Tree.NodeCollapsedFcn = createCallbackFcn(app, @TreeNodeCollapsed, true);
            app.MdNode = uitreenode(app.Tree);
            app.MdNode.Icon = 'md.png';
            app.MdNode.Text = 'Model Data';
            app.PsNode = uitreenode(app.Tree);
            app.PsNode.Icon = 'ps.png';
            app.PsNode.Text = 'Productive Structure';
            app.TsNode = uitreenode(app.Tree);
            app.TsNode.Icon = 'ts.png';
            app.TsNode.Text = 'Thermoeconomic State';
            app.TaNode = uitreenode(app.Tree);
            app.TaNode.Icon = 'ta.png';
            app.TaNode.Text = 'Thermoeconomic Analysis';
            app.WaNode = uitreenode(app.Tree);
            app.WaNode.Icon = 'wa.png';
            app.WaNode.Text = 'Waste Analysis';
            app.TdNode = uitreenode(app.Tree);
            app.TdNode.Icon = 'td.png';
            app.TdNode.Text = 'Thermoeconomic Diagnosis';
            app.SrNode = uitreenode(app.Tree);
            app.SrNode.Icon = fullfile(pathToMLAPP, 'Resources', 'sr.png');
            app.SrNode.Text = 'Summary Results';
            app.InfoPanel = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.InfoPanel);
            app.LogField = uilabel(app.GridLayout4);
            app.LogField.Text = ' Label';
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://www.exergoecology.com';
            app.Hyperlink.Text = 'www.exergoecology.com';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.OpenAsFigMenu = uimenu(app.ContextMenu);
            app.OpenAsFigMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenAsFigMenuSelected, true);
            app.OpenAsFigMenu.Text = 'Open As Fig';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SaveInWorkspaceMenu = uimenu(app.ContextMenu2);
            app.SaveInWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveInWorkspaceMenuSelected, true);
            app.SaveInWorkspaceMenu.Text = 'Save In Workspace';
            % Assign app.ContextMenu2
            app.Tree.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaesApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end