classdef ViewResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        GraphsTab         matlab.ui.container.Tab
        UIAxes            matlab.ui.control.UIAxes
        Tables            matlab.ui.container.Tab
        Label             matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        GridLayout        matlab.ui.container.GridLayout
        Tree              matlab.ui.container.Tree
        ResultsNode       matlab.ui.container.TreeNode
        ContextMenu       matlab.ui.container.ContextMenu
        OpenAsFigureMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        State % Results State
    end
    
    methods (Access = private)      
        function showGraph(app,tbl)
            obj=cResultGraph(tbl,app.State);
            N=numel(obj.Categories);
            M=numel(obj.Legend);
            app.UIAxes.Visible='off';
            app.UIAxes.Colormap=turbo(M);
            b=bar(obj.Values,...
                    'EdgeColor','none','BarWidth',0.5,...
                    'BarLayout','stacked',...
                    'BaseValue',obj.BaseValue,...
                    'FaceColor','flat',...
                    'Parent',app.UIAxes);
            for i=1:M
                b(i).CData=app.UIAxes.Colormap(i,:);
            end
            if obj.DiagnosisGraph
                bs=b.BaseLine;
                bs.BaseValue=0.0;
                bs.LineStyle='-';
                bs.Color=[0.6,0.6,0.6];
            end
            title(app.UIAxes,obj.Title,'FontSize',12);
            xlabel(app.UIAxes,obj.XAxisLabel,'FontSize',10);
            ylabel(app.UIAxes,obj.YAxisLabel,'FontSize',10);
            legend(app.UIAxes,obj.Legend,'FontSize',8);
            colorbar(app.UIAxes,'off');
            app.UIAxes.XTick=(1:N); 
            app.UIAxes.XTickLabel=obj.Categories;
            app.UIAxes.Legend.Location='northeastoutside';
            app.UIAxes.Legend.Orientation='vertical';
            app.UIAxes.Visible='on';
        end

        function showDigraph(app,tbl)
            fp=cDiagramFP(tbl);
            dg=fp.getDigraph;
            app.setColorMap;
            plot(app.UIAxes,dg,"Layout","layered","EdgeCData",dg.Edges.Weight,"EdgeColor","flat");
            c=colorbar(app.UIAxes);
            c.Label.String=['Exergy ', tbl.Unit];
            app.UIAxes.Title.String=[tbl.Description, ' [',app.State,']'];
            app.UIAxes.XLabel.String='';
            app.UIAxes.YLabel.String='';
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            legend(app.UIAxes,'off');
            app.UIAxes.Visible='on';
        end

        function setColorMap(app)
            r=(0:0.1:1); red2blue=[r.^0.4;0.2*(1-r);0.8*(1-r)]';
            app.UIAxes.Colormap=red2blue;
        end
        
        function closeApp(app)
            selection = uiconfirm(app.UIFigure,{'NO Results to show.','Close the Application?'},...
                'Confirmation');   
            switch selection
                case 'OK'
                    delete(app)                      
                case 'Cancel'
                    return
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, res)
            if isa(res,'cResultInfo')
                val={res};
            elseif isa(res,'cThermoeconomicModel')
                val=res.getResults;
            else
                app.closeApp;
                return
            end
            app.UIFigure.Name=['View Results ','[',res.ModelName,'/',res.State,']'];

            app.ResultsNode.Text='Model Results';
            for i=1:numel(val)
                tmp=val{i};
                ts=uitreenode(app.ResultsNode);
                ts.Text=tmp.Name;
                ts.NodeData=[];
                tables=fieldnames(tmp.Tables);
                for j=1:numel(tables)
                    tn=uitreenode(ts);
                    tn.Text=tables{j};
                    tn.NodeData=tmp.Tables.(tables{j});
                end
            end
            app.State=res.State;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes.NodeData)
                return
            end
            tbl=selectedNodes.NodeData;
            app.Label.Text=tbl.getDescriptionLabel;
            app.Label.Visible='on';
            colType={'char','numeric'};
            colWidth=80;
            app.UITable.ColumnName = tbl.ColNames(2:end);
            app.UITable.RowName = tbl.RowNames;
            app.UITable.ColumnWidth=repmat({colWidth},1,tbl.NrOfCols); 
            app.UITable.Data=tbl.formatData;
            %app.UITable.Position(3:4)=app.UITable.Extend(3:4);
            if isa(tbl,'cTableMatrix')
                app.UITable.ColumnFormat=repmat(colType(2),1,tbl.NrOfCols);
            else
                app.UITable.ColumnFormat=cellfun(@(x) colType(strContains(x,'f')+1),tbl.Format(3:end));
            end
            app.UITable.Visible='on';
            if isa(tbl,'cTableMatrix') 
                if tbl.isGraphTable
                    app.showGraph(tbl);
                elseif tbl.isDigraph
                    app.showDigraph(tbl);
                end
            end
        end

        % Callback function: ContextMenu, OpenAsFigureMenu
        function OpenAsFigureMenuSelected(app, event)
            newfigure = figure;
            copyobj(app.UIAxes, newfigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'View Results';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ResultsNode = uitreenode(app.Tree);
            app.ResultsNode.Text = 'Tables';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.UITable = uitable(app.Tables);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.Label = uilabel(app.Tables);
            app.Label.Visible = 'off';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @OpenAsFigureMenuSelected, true);
            app.OpenAsFigureMenu = uimenu(app.ContextMenu);
            app.OpenAsFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenAsFigureMenuSelected, true);
            app.OpenAsFigureMenu.Accelerator = 'F';
            app.OpenAsFigureMenu.Text = 'Open As Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end